classdef FourierSeriesExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesExplorerUIFigure   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadwaveformMenu                matlab.ui.container.Menu
        WaveformeditorMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TipMenu                         matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisSynthesisTab            matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ControlPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        InputsPanel                     matlab.ui.container.Panel
        ComplexcoefficientsCheckBox     matlab.ui.control.CheckBox
        WaveformperiodsSliderLabel      matlab.ui.control.Label
        WaveformperiodsSlider           matlab.ui.control.Slider
        NumberofharmonicsNSpinnerLabel  matlab.ui.control.Label
        NumberofharmonicsSpinner        matlab.ui.control.Spinner
        ExamineButtonGroup              matlab.ui.container.ButtonGroup
        PlotCoefficients                matlab.ui.control.RadioButton
        PlotHarmonics                   matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        AnalysisFormula                 matlab.ui.control.Image
        SynthesisFormula                matlab.ui.control.Image
        AnalysisLabel                   matlab.ui.control.Label
        SynthesisLabel                  matlab.ui.control.Label
        SynthesisRMSErrorEditFieldLabel  matlab.ui.control.Label
        SynthesisRMSErrorEditField      matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        WaveformAxes                    matlab.ui.control.UIAxes
        CoefficientsAxes                matlab.ui.control.UIAxes
        PropertiesTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        PropertyDropDownLabel           matlab.ui.control.Label
        PropertyDropDown                matlab.ui.control.DropDown
        TimeDomainEquation              matlab.ui.control.Image
        CoefficientEquation             matlab.ui.control.Image
        ExamineButtonGroup_2            matlab.ui.container.ButtonGroup
        TimeDomain                      matlab.ui.control.RadioButton
        FourierCoefficients             matlab.ui.control.RadioButton
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        GridLayout4                     matlab.ui.container.GridLayout
        xFunAxes                        matlab.ui.control.UIAxes
        yFunAxes                        matlab.ui.control.UIAxes
        zFunAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private, Constant)
        %MaxTime  Time span for plots
        MaxTime = 20            % seconds
        %SamplingPeriod Sampling period
        %   Even though Fourier series operates on continuous signals,
        %   computers have to work with sampled signals. Choose a sampling
        %   period small enough for the waveform periods examined in this
        %   app.
        SamplingPeriod = 1e-4   % seconds
    end
    
    properties (Access = private, Dependent)
        %MaxNumHarmonics Maximum number of harmonics
        %   Define maximum number of harmonics to support. This depends on
        %   the sampling period.
        MaxNumHarmonics
    end
    
    methods (Access = private)
        function run(app, T, N)
            Ts = app.SamplingPeriod;
            
            % Periodic waveform
            t = 0:Ts:app.MaxTime;
            f = getFunctionValues(app,t,T);
            plot(app.WaveformAxes, t, f)
            
            if app.ComplexcoefficientsCheckBox.Value
                c = analyzeComplex(app, T, N);
                
                if app.PlotCoefficients.Value
                    stem(app.CoefficientsAxes, -N:N, real(c))
                    hold(app.CoefficientsAxes, "on")
                    stem(app.CoefficientsAxes, -N:N, imag(c))
                    hold(app.CoefficientsAxes, "off")
                    legend(app.CoefficientsAxes, 'real(c)', 'imag(c)')
                else
                    plotHarmonicsComplex(app, T, N, c)
                end
                
                [f_s, t] = synthesizeComplex(app, T, N, c);
                f_s = real(f_s); % remove the small complex part due
                % to numerical errors
            else
                [a,b] = analyzeReal(app, T, N);
                
                if app.PlotCoefficients.Value
                    stem(app.CoefficientsAxes, 0:N, a)
                    hold(app.CoefficientsAxes, "on")
                    stem(app.CoefficientsAxes, 0:N, b)
                    hold(app.CoefficientsAxes, "off")
                    legend(app.CoefficientsAxes, 'a_n', 'b_n')
                else
                    plotHarmonicsReal(app, T, N, a, b)
                end
                
                [f_s, t] = synthesizeReal(app, T, N, a, b);
            end
            hold(app.WaveformAxes,"on")
            plot(app.WaveformAxes,t,f_s)
            hold(app.WaveformAxes,"off")
            legend(app.WaveformAxes, 'f(t)', 'f_s(t)')
            
            rmsError = mean(abs(f - f_s).^2);
            app.SynthesisRMSErrorEditField.Value = rmsError;
        end
        
        function c = analyzeComplex(app, T, N)
            %analyzeComplex Fourier analysis with complex valued coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:T-Ts;
            f = getFunctionValues(app,t,T);
            
            idx = 1;
            c = zeros(1,2*N+1);
            for n = -N:N
                expTerm = exp(-1i*2*pi*t*n/T);
                c(idx) = (Ts/T)*sum(f .* expTerm);
                idx = idx + 1;
            end
        end
        
        function [a,b] = analyzeReal(app, T, N)
            %analyzeReal Fourier analysis with real valued coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:T-Ts;
            f = getFunctionValues(app,t,T);
            
            idx = 1;                    % Index for a and b vectors
            a = zeros(1,N+1);           % Preallocate for speed
            b = zeros(1,N+1);           % Preallocate for speed
            for n = 0:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                a(idx) = (2*Ts/T)*sum(f .* cosTerm);    % Integrate from t = 0 to T
                b(idx) = (2*Ts/T)*sum(f .* sinTerm);    % Integrate from t = 0 to T
                idx = idx + 1;
            end
        end
        
        function [f_s,t] = synthesizeComplex(app, T, N, c)
            %synthesizeComplex Fourier synthesis with complex valued
            %coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            f_s = zeros(size(t));
            idx = 1;
            for n=-N:N
                expTerm = exp(1i*2*pi*t*n/T);
                f_s = f_s  + c(idx)*expTerm;
                idx = idx + 1;
            end
        end
        
        function [f_s,t] = synthesizeReal(app, T, N, a, b)
            %synthesizeReal Fourier synthesis with complex valued
            %coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            f_s = (a(1)/2)*ones(size(t));
            for n=1:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                % Accumulate f_s with the nth term. Since n starts from 0,
                % we use index n+1 in MATLAB, which uses 1-based indexing.
                f_s = f_s  + a(n +1)*cosTerm + b(n +1)*sinTerm;
            end
        end
        
        function f = getFunctionValues(app,t,T)
            A = 2;
            f = zeros(size(t));
            f(t < (T/2)) = A;           % Set f(t) to A for t values less than T/2.
            f((t>=(T/2)) & (t<T)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
            
            % Check if t has values greater than T
            if any(t > T)
                f(mod(t,T) < (T/2)) = A;           % Set f(t) to A for t values less than T/2.
                f(mod(t,T)>=(T/2)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
            end
        end
        
        function plotHarmonicsComplex(app, T, N, c)
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            
            legendStr = cell(1,2*N+1);
            idx = 1;
            for n=-N:N
                expTerm = exp(1i*2*pi*t*n/T);
                harmonic = c(idx)*expTerm;
                plot(app.CoefficientsAxes, t, real(harmonic))
                hold(app.CoefficientsAxes, "on")
                plot(app.CoefficientsAxes, t, imag(harmonic), '--')
                legendStr{(idx-1)*2+1} = sprintf("n=%d - real", n);
                legendStr{(idx-1)*2+2} = sprintf("n=%d - imag", n);
                idx = idx + 1;
            end
            hold(app.CoefficientsAxes, "off")
            legend(app.CoefficientsAxes, legendStr{:})
        end
        
        function plotHarmonicsReal(app, T, N, a, b)
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            harmonic = (a(1)/2)*ones(size(t));
            plot(app.CoefficientsAxes, t, harmonic)
            hold(app.CoefficientsAxes, "on")
            legendStr = cell(1,N+1);
            legendStr{1} = "n=0";
            for n=1:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                harmonic = a(n +1)*cosTerm + b(n +1)*sinTerm;
                plot(app.CoefficientsAxes, t, harmonic)
                legendStr{n +1} = sprintf("n=%d", n);
            end
            hold(app.CoefficientsAxes, "off")
            legend(app.CoefficientsAxes, legendStr{:})
        end
        
        function checkMaxNumHarmonics(app)
            if app.ComplexcoefficientsCheckBox.Value
                Nmax = 3;
            else
                Nmax = 10;
            end
            if app.NumberofharmonicsSpinner.Value > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
                uialert(app.FourierSeriesExplorerUIFigure, ...
                    sprintf("Maximum number of harmonics supported for " ...
                    + "plotting is %d. Number of harmonics is set to %d.", ...
                    Nmax, Nmax), ...
                    "Fourier Series Explorer", "Icon","warning")
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
        end
        
        function updatePropertiesTab(app)
            switch app.PropertyDropDown.Value
                case 'Linearity'
                    updateLinearity(app)
                case 'Time reversal'
                    updateTimeReversal(app)
            end
            
        end
        
        function updateTimeReversal(app)
            app.TimeDomainEquation.ImageSource = 'timerevelsal_time.PNG';
            app.CoefficientEquation.ImageSource = 'timerevelsal_frequency.PNG';
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            
        end
        
        function updateLinearity(app)
            app.TimeDomainEquation.ImageSource = 'linearity_time.PNG';
            app.CoefficientEquation.ImageSource = 'linearity_frequency.PNG';
            app.aEditField.Visible = "on";
            app.bEditField.Visible = "on";
            app.aEditFieldLabel.Visible = "on";
            app.bEditFieldLabel.Visible = "on";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes,t,x)
                title(app.xFunAxes,"Function x(t)")
                ylabel(app.xFunAxes,"x(t)")
                xlabel(app.xFunAxes,"Time (s)")
                
                y = yValues(app);
                plot(app.yFunAxes,t,y)
                title(app.yFunAxes,"Function y(t)")
                ylabel(app.yFunAxes,"y(t)")
                xlabel(app.yFunAxes,"Time (s)")
                
                z = str2double(app.aEditField.Value) * x + app.bEditField.Value * y;
                plot(app.zFunAxes,t,real(z))
                hold(app.zFunAxes, "on")
                plot(app.zFunAxes,t,imag(z))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Function z(t)")
                ylabel(app.zFunAxes,"z(t)")
                xlabel(app.zFunAxes,"Time (s)")
                legend('real','imag')
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes,-N:N,real(x_n))
                hold(app.xFunAxes, "on")
                stem(app.xFunAxes,-N:N,imag(x_n))
                hold(app.xFunAxes, "off")
                title(app.xFunAxes,"Coefficients x_n")
                ylabel(app.xFunAxes,"Amplitude")
                xlabel(app.xFunAxes,"Coeffiicent index (n)")
                legend('real','imag')
                
                y = yValues(app);
                y_n = analysis(app,y,t,N);
                stem(app.yFunAxes,-N:N,real(y_n))
                hold(app.yFunAxes, "on")
                stem(app.yFunAxes,-N:N,imag(y_n))
                hold(app.yFunAxes, "off")
                title(app.yFunAxes,"Coefficients y_n")
                ylabel(app.yFunAxes,"Amplitude")
                xlabel(app.yFunAxes,"Coeffiicent index (n)")
                legend('real','imag')
                
                z = str2double(app.aEditField.Value) * x + app.bEditField.Value * y;
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes,-N:N,real(z_n))
                hold(app.zFunAxes, "on")
                stem(app.zFunAxes,-N:N,imag(z_n))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Coefficients z_n")
                ylabel(app.zFunAxes,"Amplitude")
                xlabel(app.zFunAxes,"Coeffiicent index (n)")
                legend('real','imag')
            end
        end
        
        function [x,t] = xValues(app)
            %xValues  Values of function x for one period
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            t = 0:Ts:T-Ts;
            x = zeros(size(t));
            
            % x = 2*t for 0<t<T/2
            idx = t < T/2;
            x(idx) = 2*t(idx);
            
            % x = 2*T-2*t for T/2<t<T
            idx = (t >= T/2) & (t < T);
            x(idx) = 2*T - 2*t(idx);
        end
        
        function [y,t] = yValues(app)
            %xValues  Values of function y for one period
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            t = 0:Ts:T-Ts;
            y = zeros(size(t));
            
            % y = 2*t for 0<t<T/4
            idx = t < T/4;
            y(idx) = 2*t(idx);
            
            % y = T for T/4<t<T/2
            idx = (t >= T/4) & (t < T/2);
            y(idx) = T/2;
            
            % y = T + exp(-(t+T/2)) for T/2<t<T
            idx = (t >= T/2) & (t < T);
            y(idx) = T/2 - 1 + exp(-(t(idx)-T/2));
        end
        
        function c = analysis(app,f,t,N)
            %analysis Fourier analysis
            
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            idx = 1;
            c = zeros(1,2*N+1);
            for n = -N:N
                expTerm = exp(-1i*2*pi*t*n/T);
                c(idx) = (Ts/T)*sum(f .* expTerm);
                idx = idx + 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Analysis - Synthesis Tab
            T = app.WaveformperiodsSlider.Value;
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, T, N);
            
            % Properties Tab
            updatePropertiesTab(app)
        end

        % Value changing function: WaveformperiodsSlider
        function WaveformperiodsSliderValueChanging(app, event)
            T = event.Value;
            Ts = app.SamplingPeriod;
            Nmax = floor(T / (2*Ts));
            N = floor(app.NumberofharmonicsSpinner.Value);
            if N > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
                N = Nmax;
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            run(app, T, N);
        end

        % Value changing function: NumberofharmonicsSpinner
        function NumberofharmonicsSpinnerValueChanging(app, event)
            T = app.WaveformperiodsSlider.Value;
            N = floor(event.Value);
            run(app, T, N);
        end

        % Value changed function: ComplexcoefficientsCheckBox
        function ComplexcoefficientsCheckBoxValueChanged(app, event)
            value = app.ComplexcoefficientsCheckBox.Value;
            if value
                app.AnalysisFormula.ImageSource = ...
                    "analysis_complex_coeffs.PNG";
                app.SynthesisFormula.ImageSource = ...
                    "synthesis_complex_coeffs.PNG";
            else
                app.AnalysisFormula.ImageSource = ...
                    "analysis_real_coeffs.PNG";
                app.SynthesisFormula.ImageSource = ...
                    "synthesis_real_coeffs.PNG";
            end
            T = app.WaveformperiodsSlider.Value;
            if app.ExamineButtonGroup.SelectedObject == app.PlotHarmonics
                checkMaxNumHarmonics(app)
            end
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, T, N);
        end

        % Selection changed function: ExamineButtonGroup
        function ExamineButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExamineButtonGroup.SelectedObject;
            if selectedButton == app.PlotHarmonics
                checkMaxNumHarmonics(app)
                title(app.CoefficientsAxes, "Harmonics")
                xlabel(app.CoefficientsAxes, "t (s)")
            else
                title(app.CoefficientsAxes, "Fourier Series Coefficients")
                xlabel(app.CoefficientsAxes, "Coefficient Index (n)")
                T = app.WaveformperiodsSlider.Value;
                Ts = app.SamplingPeriod;
                Nmax = floor(T / (2*Ts));
                N = floor(app.NumberofharmonicsSpinner.Value);
                if N > Nmax
                    app.NumberofharmonicsSpinner.Value = Nmax;
                end
                app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            end
            T = app.WaveformperiodsSlider.Value;
            N = app.NumberofharmonicsSpinner.Value;
            run(app, T, N)
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            updateLinearity(app)
        end

        % Selection changed function: ExamineButtonGroup_2
        function ExamineButtonGroup_2SelectionChanged(app, event)
            updatePropertiesTab(app)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            updateLinearity(app)
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            updatePropertiesTab(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSeriesExplorerUIFigure = uifigure('Visible', 'off');
            app.FourierSeriesExplorerUIFigure.Name = 'Fourier Series Explorer';
            app.FourierSeriesExplorerUIFigure.Tag = 'FourierSeriesExplorer';
            app.FileMenu = uimenu(app.FourierSeriesExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadwaveformMenu = uimenu(app.FileMenu);
            app.LoadwaveformMenu.Text = 'Load waveform...';
            app.WaveformeditorMenu = uimenu(app.FileMenu);
            app.WaveformeditorMenu.Text = 'Waveform editor...';
            app.HelpMenu = uimenu(app.FourierSeriesExplorerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TipMenu = uimenu(app.HelpMenu);
            app.TipMenu.Text = 'Tip...';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About...';
            app.TabGroup = uitabgroup(app.FourierSeriesExplorerUIFigure);
            app.AnalysisSynthesisTab = uitab(app.TabGroup);
            app.AnalysisSynthesisTab.Title = 'Analysis & Synthesis';
            app.GridLayout = uigridlayout(app.AnalysisSynthesisTab);
            app.ControlPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ControlPanel);
            app.InputsPanel = uipanel(app.GridLayout2);
            app.ComplexcoefficientsCheckBox = uicheckbox(app.InputsPanel);
            app.ComplexcoefficientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComplexcoefficientsCheckBoxValueChanged, true);
            app.ComplexcoefficientsCheckBox.Text = 'Complex coefficients';
            app.WaveformperiodsSliderLabel = uilabel(app.InputsPanel);
            app.WaveformperiodsSliderLabel.Text = 'Waveform period (s):';
            app.WaveformperiodsSlider = uislider(app.InputsPanel);
            app.WaveformperiodsSlider.Limits = [1 20];
            app.WaveformperiodsSlider.MajorTicks = [1 5 10 15 20];
            app.WaveformperiodsSlider.MajorTickLabels = {'1', '5', '10', '15', '20'};
            app.WaveformperiodsSlider.ValueChangingFcn = createCallbackFcn(app, @WaveformperiodsSliderValueChanging, true);
            app.WaveformperiodsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.WaveformperiodsSlider.Value = 10;
            app.NumberofharmonicsNSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofharmonicsNSpinnerLabel.Text = 'Number of harmonics (N):';
            app.NumberofharmonicsSpinner = uispinner(app.InputsPanel);
            app.NumberofharmonicsSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofharmonicsSpinnerValueChanging, true);
            app.NumberofharmonicsSpinner.Limits = [0 500];
            app.NumberofharmonicsSpinner.Value = 30;
            app.ExamineButtonGroup = uibuttongroup(app.InputsPanel);
            app.ExamineButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExamineButtonGroupSelectionChanged, true);
            app.ExamineButtonGroup.Title = 'Examine:';
            app.PlotCoefficients = uiradiobutton(app.ExamineButtonGroup);
            app.PlotCoefficients.Text = 'Coefficients';
            app.PlotCoefficients.Value = true;
            app.PlotHarmonics = uiradiobutton(app.ExamineButtonGroup);
            app.PlotHarmonics.Text = 'Harmonics';
            app.Panel = uipanel(app.GridLayout2);
            app.AnalysisFormula = uiimage(app.Panel);
            app.AnalysisFormula.ImageSource = 'analysis_real_coeffs.PNG';
            app.SynthesisFormula = uiimage(app.Panel);
            app.SynthesisFormula.ImageSource = 'synthesis_real_coeffs.PNG';
            app.AnalysisLabel = uilabel(app.Panel);
            app.AnalysisLabel.Text = 'Analysis:';
            app.SynthesisLabel = uilabel(app.Panel);
            app.SynthesisLabel.Text = 'Synthesis:';
            app.SynthesisRMSErrorEditFieldLabel = uilabel(app.Panel);
            app.SynthesisRMSErrorEditFieldLabel.Text = 'Synthesis RMS Error:';
            app.SynthesisRMSErrorEditField = uieditfield(app.Panel, 'numeric');
            app.SynthesisRMSErrorEditField.Editable = 'off';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.WaveformAxes = uiaxes(app.ResultsPanel);
            title(app.WaveformAxes, 'Fourier Series Approximation')
            xlabel(app.WaveformAxes, 't (s)')
            ylabel(app.WaveformAxes, 'f(t)')
            app.CoefficientsAxes = uiaxes(app.ResultsPanel);
            title(app.CoefficientsAxes, 'Fourier Series Coefficients')
            xlabel(app.CoefficientsAxes, 'Coefficient Index, n')
            ylabel(app.CoefficientsAxes, 'Amplitude')
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout3 = uigridlayout(app.PropertiesTab);
            app.Panel_2 = uipanel(app.GridLayout3);
            app.PropertyDropDownLabel = uilabel(app.Panel_2);
            app.PropertyDropDownLabel.Text = 'Property:';
            app.PropertyDropDown = uidropdown(app.Panel_2);
            app.PropertyDropDown.Items = {'Linearity', 'Time reversal', 'Time scaling', 'Time shift', 'Convolution in Time', 'Convolution in frequency', 'Symmetry'};
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
            app.PropertyDropDown.Value = 'Linearity';
            app.TimeDomainEquation = uiimage(app.Panel_2);
            app.TimeDomainEquation.ImageSource = 'linearity_time.PNG';
            app.CoefficientEquation = uiimage(app.Panel_2);
            app.CoefficientEquation.ImageSource = 'linearity_frequency.PNG';
            app.ExamineButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ExamineButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ExamineButtonGroup_2SelectionChanged, true);
            app.ExamineButtonGroup_2.Title = 'Examine:';
            app.TimeDomain = uiradiobutton(app.ExamineButtonGroup_2);
            app.TimeDomain.Text = 'Time domain';
            app.TimeDomain.Value = true;
            app.FourierCoefficients = uiradiobutton(app.ExamineButtonGroup_2);
            app.FourierCoefficients.Text = 'Fourier Coefficients';
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 2;
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.Panel_2, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.xFunAxes = uiaxes(app.GridLayout4);
            title(app.xFunAxes, 'Function x(t)')
            xlabel(app.xFunAxes, 'Time (s)')
            ylabel(app.xFunAxes, 'x(t)')
            app.yFunAxes = uiaxes(app.GridLayout4);
            title(app.yFunAxes, 'Function y(t)')
            xlabel(app.yFunAxes, 'Time (s)')
            ylabel(app.yFunAxes, 'y(t)')
            app.zFunAxes = uiaxes(app.GridLayout4);
            title(app.zFunAxes, 'Function z(t)')
            xlabel(app.zFunAxes, 'Time (s)')
            ylabel(app.zFunAxes, 'z(t)')
            % Show the figure after all components are created
            app.FourierSeriesExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesExplorerUIFigure)
        end
    end
end